classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure        matlab.ui.Figure
        dog_new_19                      matlab.ui.control.Image
        battleship_new_19               matlab.ui.control.Image
        hat_new_19                      matlab.ui.control.Image
        car_new_19                      matlab.ui.control.Image
        dog_tennessee_18                matlab.ui.control.Image
        battleship_tennessee_18         matlab.ui.control.Image
        hat_tennessee_18                matlab.ui.control.Image
        car_tennessee_18                matlab.ui.control.Image
        dog_comm_17                     matlab.ui.control.Image
        battleship_comm_17              matlab.ui.control.Image
        hat_comm_17                     matlab.ui.control.Image
        car_comm_17                     matlab.ui.control.Image
        dog_stjames_16                  matlab.ui.control.Image
        battleship_stjames_16           matlab.ui.control.Image
        hat_stjames_16                  matlab.ui.control.Image
        car_stjames_16                  matlab.ui.control.Image
        dog_pennsylvania_15             matlab.ui.control.Image
        battleship_pennsylvania_15      matlab.ui.control.Image
        hat_pennsylvania_15             matlab.ui.control.Image
        car_pennsylvania_15             matlab.ui.control.Image
        dog_virginia_14                 matlab.ui.control.Image
        battleship_virginia_14          matlab.ui.control.Image
        hat_virginia_14                 matlab.ui.control.Image
        car_virginia_14                 matlab.ui.control.Image
        dog_states_13                   matlab.ui.control.Image
        battleship_states_13            matlab.ui.control.Image
        hat_states_13                   matlab.ui.control.Image
        car_states_13                   matlab.ui.control.Image
        dog_electric_12                 matlab.ui.control.Image
        battleship_electric_12          matlab.ui.control.Image
        hat_electric_12                 matlab.ui.control.Image
        car_electric_12                 matlab.ui.control.Image
        dog_stcharles_11                matlab.ui.control.Image
        battleship_stcharles_11         matlab.ui.control.Image
        hat_stcharles_11                matlab.ui.control.Image
        car_stcharles_11                matlab.ui.control.Image
        dog_boardwalk_39                matlab.ui.control.Image
        battleship_boardwalk_39         matlab.ui.control.Image
        hat_boardwalk_39                matlab.ui.control.Image
        car_boardwalk_39                matlab.ui.control.Image
        dog_luxary_38                   matlab.ui.control.Image
        battleship_luxary_38            matlab.ui.control.Image
        hat_luxary_38                   matlab.ui.control.Image
        car_luxary_38                   matlab.ui.control.Image
        dog_park_37                     matlab.ui.control.Image
        battleship_park_37              matlab.ui.control.Image
        hat_park_37                     matlab.ui.control.Image
        car_park_37                     matlab.ui.control.Image
        dog_chance_36                   matlab.ui.control.Image
        battleship_chance_36            matlab.ui.control.Image
        hat_chance_36                   matlab.ui.control.Image
        car_chance_36                   matlab.ui.control.Image
        dog_short_35                    matlab.ui.control.Image
        battleship_short_35             matlab.ui.control.Image
        hat_short_35                    matlab.ui.control.Image
        car_short_35                    matlab.ui.control.Image
        dog_pennsylvania_34             matlab.ui.control.Image
        battleship_pennsylvania_34      matlab.ui.control.Image
        hat_pennsylvania_34             matlab.ui.control.Image
        car_pennsylvania_34             matlab.ui.control.Image
        dog_comm_33                     matlab.ui.control.Image
        battleship_comm_33              matlab.ui.control.Image
        hat_comm_33                     matlab.ui.control.Image
        car_comm_33                     matlab.ui.control.Image
        dog_north_32                    matlab.ui.control.Image
        battleship_north_32             matlab.ui.control.Image
        hat_north_32                    matlab.ui.control.Image
        car_north_32                    matlab.ui.control.Image
        dog_pacific_31                  matlab.ui.control.Image
        battleship_pacific_31           matlab.ui.control.Image
        hat_pacific_31                  matlab.ui.control.Image
        car_pacific_31                  matlab.ui.control.Image
        dog_goto_30                     matlab.ui.control.Image
        battleship_goto_30              matlab.ui.control.Image
        hat_goto_30                     matlab.ui.control.Image
        car_goto_30                     matlab.ui.control.Image
        dog_chance_22                   matlab.ui.control.Image
        battleship_chance_22            matlab.ui.control.Image
        hat_chance_22                   matlab.ui.control.Image
        car_chance_22                   matlab.ui.control.Image
        dog_marvin_29                   matlab.ui.control.Image
        battleship_marvin_29            matlab.ui.control.Image
        hat_marvin_29                   matlab.ui.control.Image
        car_marvin_29                   matlab.ui.control.Image
        dog_water_28                    matlab.ui.control.Image
        battleship_water_28             matlab.ui.control.Image
        hat_water_28                    matlab.ui.control.Image
        car_water_28                    matlab.ui.control.Image
        dog_ventnor_27                  matlab.ui.control.Image
        battleship_ventnor_27           matlab.ui.control.Image
        hat_ventnor_27                  matlab.ui.control.Image
        car_ventnor_27                  matlab.ui.control.Image
        dog_atlantic_26                 matlab.ui.control.Image
        battleship_atlantic_26          matlab.ui.control.Image
        hat_atlantic_26                 matlab.ui.control.Image
        car_atlantic_26                 matlab.ui.control.Image
        dog_bando_25                    matlab.ui.control.Image
        battleship_bando_25             matlab.ui.control.Image
        hat_bando_25                    matlab.ui.control.Image
        car_bando_25                    matlab.ui.control.Image
        dog_illinois_24                 matlab.ui.control.Image
        battleship_illinois_24          matlab.ui.control.Image
        hat_illinois_24                 matlab.ui.control.Image
        car_illinois_24                 matlab.ui.control.Image
        dog_indiana_23                  matlab.ui.control.Image
        battleship_indiana_23           matlab.ui.control.Image
        hat_indiana_23                  matlab.ui.control.Image
        car_indiana_23                  matlab.ui.control.Image
        dog_kentucky_21                 matlab.ui.control.Image
        battleship_kentucky_21          matlab.ui.control.Image
        hat_kentucky_21                 matlab.ui.control.Image
        car_kentucky_21                 matlab.ui.control.Image
        dog_free_20                     matlab.ui.control.Image
        battleship_free_20              matlab.ui.control.Image
        hat_free_20                     matlab.ui.control.Image
        car_free_20                     matlab.ui.control.Image
        hat_visiting_10                 matlab.ui.control.Image
        car_visiting_10                 matlab.ui.control.Image
        dog_jail_10                     matlab.ui.control.Image
        battleship_jail_10              matlab.ui.control.Image
        dog_visiting_10                 matlab.ui.control.Image
        battleship_visiting_10          matlab.ui.control.Image
        hat_jail_10                     matlab.ui.control.Image
        car_jail_10                     matlab.ui.control.Image
        dog_conneticut_9                matlab.ui.control.Image
        battleship_conneticut_9         matlab.ui.control.Image
        hat_conneticut_9                matlab.ui.control.Image
        car_conneticut_9                matlab.ui.control.Image
        dog_vermont_8                   matlab.ui.control.Image
        battleship_vermont_8            matlab.ui.control.Image
        hat_vermont_8                   matlab.ui.control.Image
        car_vermont_8                   matlab.ui.control.Image
        dog_chance_7                    matlab.ui.control.Image
        battleship_chance_7             matlab.ui.control.Image
        hat_chance_7                    matlab.ui.control.Image
        car_chance_7                    matlab.ui.control.Image
        dog_oriental_6                  matlab.ui.control.Image
        battleship_oriental_6           matlab.ui.control.Image
        hat_oriental_6                  matlab.ui.control.Image
        car_oriental_6                  matlab.ui.control.Image
        dog_reading_5                   matlab.ui.control.Image
        battleship_reading_5            matlab.ui.control.Image
        hat_reading_5                   matlab.ui.control.Image
        car_reading_5                   matlab.ui.control.Image
        dog_income_4                    matlab.ui.control.Image
        battleship_income_4             matlab.ui.control.Image
        hat_income_4                    matlab.ui.control.Image
        car_income_4                    matlab.ui.control.Image
        dog_baltic_3                    matlab.ui.control.Image
        battleship_baltic_3             matlab.ui.control.Image
        hat_baltic_3                    matlab.ui.control.Image
        car_baltic_3                    matlab.ui.control.Image
        dog_comm_2                      matlab.ui.control.Image
        battleship_comm_2               matlab.ui.control.Image
        hat_comm_2                      matlab.ui.control.Image
        car_comm_2                      matlab.ui.control.Image
        dog_mediter_1                   matlab.ui.control.Image
        battleship_mediter_1            matlab.ui.control.Image
        hat_mediter_1                   matlab.ui.control.Image
        car_mediter_1                   matlab.ui.control.Image
        dog_go_0                        matlab.ui.control.Image
        battleship_go_0                 matlab.ui.control.Image
        hat_go_0                        matlab.ui.control.Image
        car_go_0                        matlab.ui.control.Image
        propertiesLabel                 matlab.ui.control.Label
        Player4Label                    matlab.ui.control.Label
        Player3Label                    matlab.ui.control.Label
        EndTurnButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        YouvegonebankruptLabel          matlab.ui.control.Label
        UseGetoutofjailfreecardButton   matlab.ui.control.Button
        OptionsLabel                    matlab.ui.control.Label
        Player2Label                    matlab.ui.control.Label
        Player1Label                    matlab.ui.control.Label
        CurrentSpotOptionsLabel         matlab.ui.control.Label
        Image3_4                        matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        BuyahouseButton                 matlab.ui.control.Button
        BuyahotelButton                 matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        BuyPropertyHereButton           matlab.ui.control.Button
        SellAPropertyButton             matlab.ui.control.Button
        LeavegameButton                 matlab.ui.control.Button
        CurrentTurnGamenotstartedLabel  matlab.ui.control.Label
        YourPropertiesLabel             matlab.ui.control.Label
        YourFunds100Label               matlab.ui.control.Label
        PlayersConnectedLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        ChatEditField                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        GameInstance % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roomId, userIsHost, localPlayerId)
            import Game.*;
            import StompClient.*;
            
            app.GameInstance = Game.gameInstance(app, roomId, userIsHost, localPlayerId);
            app.GameInstance.setStompClient(StompClient.getInstance());
        end

        % Button pushed function: BuyahouseButton
        function BuyahouseButtonPushed(app, event)
            if(app.Panel.Visible == "on")
                app.Panel.Visible = "off";
            else
                app.Panel.Visible = "on";
            end
        end

        % Button pushed function: LeavegameButton
        function LeavegameButtonPushed(app, event)
            delete(app);
            main_menu();
        end

        % Window key press function: MonopolyInMatlabUIFigure
        function MonopolyInMatlabUIFigureWindowKeyPress(app, event)

            if(strcmp(event.Key, "return"))
                focusedComponent = app.MonopolyInMatlabUIFigure.CurrentObject;

                if((focusedComponent == app.ChatEditField) && ~isempty(app.ChatEditField.Value))
                    app.GameInstance.sendChatMessage(app.ChatEditField.Value);
                    app.ChatEditField.Value = "";
                end
            end
            
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            app.EndTurnButton.Enable = "off";
            switch(app.EndTurnButton.Text)
                case "Start Game"
                    if(app.GameInstance.weAreHost == true)
                        app.GameInstance.requestStartGame();
                    end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.MonopolyInMatlabUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MonopolyInMatlabUIFigureWindowKeyPress, true);
            app.ChatEditField = uieditfield(app.MonopolyInMatlabUIFigure, 'text');
            app.ChatEditField.Placeholder = 'Enter message';
            app.TextArea = uitextarea(app.MonopolyInMatlabUIFigure);
            app.TextArea.Editable = 'off';
            app.Image = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image.ImageSource = 'monopoly_board.jpg';
            app.PlayersConnectedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.PlayersConnectedLabel.Text = 'Players Connected(N/A): ';
            app.YourFunds100Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourFunds100Label.Text = 'Your Funds: $100';
            app.YourPropertiesLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourPropertiesLabel.Text = 'Your Properties:';
            app.CurrentTurnGamenotstartedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentTurnGamenotstartedLabel.Text = 'Current Turn: Game not started';
            app.LeavegameButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.LeavegameButton.ButtonPushedFcn = createCallbackFcn(app, @LeavegameButtonPushed, true);
            app.LeavegameButton.Text = 'Leave game';
            app.SellAPropertyButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.SellAPropertyButton.Enable = 'off';
            app.SellAPropertyButton.Text = 'Sell A Property';
            app.BuyPropertyHereButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyPropertyHereButton.Enable = 'off';
            app.BuyPropertyHereButton.Text = 'Buy Property Here';
            app.Image2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image2.ImageSource = 'hotel.png';
            app.BuyahotelButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahotelButton.Enable = 'off';
            app.BuyahotelButton.Text = 'Buy a hotel';
            app.BuyahouseButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahouseButton.ButtonPushedFcn = createCallbackFcn(app, @BuyahouseButtonPushed, true);
            app.BuyahouseButton.Enable = 'off';
            app.BuyahouseButton.Text = 'Buy a house';
            app.Image3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3.ImageSource = 'house.png';
            app.Image3_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_2.ImageSource = 'house.png';
            app.Image3_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_3.ImageSource = 'house.png';
            app.Image3_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_4.ImageSource = 'house.png';
            app.CurrentSpotOptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentSpotOptionsLabel.Enable = 'off';
            app.CurrentSpotOptionsLabel.Text = 'Current Spot Options';
            app.Player1Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player1Label.Text = '';
            app.Player2Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player2Label.Text = '';
            app.OptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.OptionsLabel.Enable = 'off';
            app.OptionsLabel.Text = 'Options';
            app.UseGetoutofjailfreecardButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.UseGetoutofjailfreecardButton.Enable = 'off';
            app.UseGetoutofjailfreecardButton.Text = 'Use Get out of jail free card';
            app.Panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.Panel.Visible = 'off';
            app.YouvegonebankruptLabel = uilabel(app.Panel);
            app.YouvegonebankruptLabel.Text = 'Youve gone bankrupt!';
            app.EndTurnButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.Player3Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player3Label.Text = '';
            app.Player4Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player4Label.Text = '';
            app.propertiesLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.propertiesLabel.Text = '';
            app.car_go_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_go_0.Visible = 'off';
            app.car_go_0.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_go_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_go_0.Visible = 'off';
            app.hat_go_0.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_go_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_go_0.Visible = 'off';
            app.battleship_go_0.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_go_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_go_0.Visible = 'off';
            app.dog_go_0.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_mediter_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_mediter_1.Visible = 'off';
            app.car_mediter_1.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_mediter_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_mediter_1.Visible = 'off';
            app.hat_mediter_1.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_mediter_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_mediter_1.Visible = 'off';
            app.battleship_mediter_1.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_mediter_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_mediter_1.Visible = 'off';
            app.dog_mediter_1.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_comm_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_comm_2.Visible = 'off';
            app.car_comm_2.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_comm_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_comm_2.Visible = 'off';
            app.hat_comm_2.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_comm_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_comm_2.Visible = 'off';
            app.battleship_comm_2.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_comm_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_comm_2.Visible = 'off';
            app.dog_comm_2.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_baltic_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_baltic_3.Visible = 'off';
            app.car_baltic_3.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_baltic_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_baltic_3.Visible = 'off';
            app.hat_baltic_3.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_baltic_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_baltic_3.Visible = 'off';
            app.battleship_baltic_3.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_baltic_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_baltic_3.Visible = 'off';
            app.dog_baltic_3.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_income_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_income_4.Visible = 'off';
            app.car_income_4.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_income_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_income_4.Visible = 'off';
            app.hat_income_4.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_income_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_income_4.Visible = 'off';
            app.battleship_income_4.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_income_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_income_4.Visible = 'off';
            app.dog_income_4.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_reading_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_reading_5.Visible = 'off';
            app.car_reading_5.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_reading_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_reading_5.Visible = 'off';
            app.hat_reading_5.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_reading_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_reading_5.Visible = 'off';
            app.battleship_reading_5.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_reading_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_reading_5.Visible = 'off';
            app.dog_reading_5.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_oriental_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_oriental_6.Visible = 'off';
            app.car_oriental_6.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_oriental_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_oriental_6.Visible = 'off';
            app.hat_oriental_6.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_oriental_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_oriental_6.Visible = 'off';
            app.battleship_oriental_6.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_oriental_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_oriental_6.Visible = 'off';
            app.dog_oriental_6.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_chance_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_chance_7.Visible = 'off';
            app.car_chance_7.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_chance_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_chance_7.Visible = 'off';
            app.hat_chance_7.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_chance_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_chance_7.Visible = 'off';
            app.battleship_chance_7.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_chance_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_chance_7.Visible = 'off';
            app.dog_chance_7.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_vermont_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_vermont_8.Visible = 'off';
            app.car_vermont_8.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_vermont_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_vermont_8.Visible = 'off';
            app.hat_vermont_8.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_vermont_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_vermont_8.Visible = 'off';
            app.battleship_vermont_8.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_vermont_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_vermont_8.Visible = 'off';
            app.dog_vermont_8.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_conneticut_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_conneticut_9.Visible = 'off';
            app.car_conneticut_9.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_conneticut_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_conneticut_9.Visible = 'off';
            app.hat_conneticut_9.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_conneticut_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_conneticut_9.Visible = 'off';
            app.battleship_conneticut_9.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_conneticut_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_conneticut_9.Visible = 'off';
            app.dog_conneticut_9.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_jail_10.Visible = 'off';
            app.car_jail_10.ImageSource = fullfile(pathToMLAPP, 'car.png');
            app.hat_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_jail_10.Visible = 'off';
            app.hat_jail_10.ImageSource = fullfile(pathToMLAPP, 'hat.png');
            app.battleship_visiting_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_visiting_10.Visible = 'off';
            app.battleship_visiting_10.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_visiting_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_visiting_10.Visible = 'off';
            app.dog_visiting_10.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.battleship_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_jail_10.Visible = 'off';
            app.battleship_jail_10.ImageSource = fullfile(pathToMLAPP, 'battleship.png');
            app.dog_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_jail_10.Visible = 'off';
            app.dog_jail_10.ImageSource = fullfile(pathToMLAPP, 'dog.png');
            app.car_visiting_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_visiting_10.Visible = 'off';
            app.car_visiting_10.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_visiting_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_visiting_10.Visible = 'off';
            app.hat_visiting_10.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.car_free_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_free_20.Visible = 'off';
            app.car_free_20.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_free_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_free_20.Visible = 'off';
            app.hat_free_20.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_free_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_free_20.Visible = 'off';
            app.battleship_free_20.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_free_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_free_20.Visible = 'off';
            app.dog_free_20.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_kentucky_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_kentucky_21.Visible = 'off';
            app.car_kentucky_21.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_kentucky_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_kentucky_21.Visible = 'off';
            app.hat_kentucky_21.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_kentucky_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_kentucky_21.Visible = 'off';
            app.battleship_kentucky_21.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_kentucky_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_kentucky_21.Visible = 'off';
            app.dog_kentucky_21.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_indiana_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_indiana_23.Visible = 'off';
            app.car_indiana_23.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_indiana_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_indiana_23.Visible = 'off';
            app.hat_indiana_23.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_indiana_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_indiana_23.Visible = 'off';
            app.battleship_indiana_23.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_indiana_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_indiana_23.Visible = 'off';
            app.dog_indiana_23.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_illinois_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_illinois_24.Visible = 'off';
            app.car_illinois_24.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_illinois_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_illinois_24.Visible = 'off';
            app.hat_illinois_24.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_illinois_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_illinois_24.Visible = 'off';
            app.battleship_illinois_24.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_illinois_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_illinois_24.Visible = 'off';
            app.dog_illinois_24.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_bando_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_bando_25.Visible = 'off';
            app.car_bando_25.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_bando_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_bando_25.Visible = 'off';
            app.hat_bando_25.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_bando_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_bando_25.Visible = 'off';
            app.battleship_bando_25.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_bando_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_bando_25.Visible = 'off';
            app.dog_bando_25.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_atlantic_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_atlantic_26.Visible = 'off';
            app.car_atlantic_26.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_atlantic_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_atlantic_26.Visible = 'off';
            app.hat_atlantic_26.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_atlantic_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_atlantic_26.Visible = 'off';
            app.battleship_atlantic_26.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_atlantic_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_atlantic_26.Visible = 'off';
            app.dog_atlantic_26.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_ventnor_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_ventnor_27.Visible = 'off';
            app.car_ventnor_27.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_ventnor_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_ventnor_27.Visible = 'off';
            app.hat_ventnor_27.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_ventnor_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_ventnor_27.Visible = 'off';
            app.battleship_ventnor_27.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_ventnor_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_ventnor_27.Visible = 'off';
            app.dog_ventnor_27.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_water_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_water_28.Visible = 'off';
            app.car_water_28.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_water_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_water_28.Visible = 'off';
            app.hat_water_28.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_water_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_water_28.Visible = 'off';
            app.battleship_water_28.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_water_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_water_28.Visible = 'off';
            app.dog_water_28.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_marvin_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_marvin_29.Visible = 'off';
            app.car_marvin_29.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_marvin_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_marvin_29.Visible = 'off';
            app.hat_marvin_29.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_marvin_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_marvin_29.Visible = 'off';
            app.battleship_marvin_29.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_marvin_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_marvin_29.Visible = 'off';
            app.dog_marvin_29.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_chance_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_chance_22.Visible = 'off';
            app.car_chance_22.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_chance_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_chance_22.Visible = 'off';
            app.hat_chance_22.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_chance_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_chance_22.Visible = 'off';
            app.battleship_chance_22.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_chance_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_chance_22.Visible = 'off';
            app.dog_chance_22.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_goto_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_goto_30.Visible = 'off';
            app.car_goto_30.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_goto_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_goto_30.Visible = 'off';
            app.hat_goto_30.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_goto_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_goto_30.Visible = 'off';
            app.battleship_goto_30.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_goto_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_goto_30.Visible = 'off';
            app.dog_goto_30.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_pacific_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_pacific_31.Visible = 'off';
            app.car_pacific_31.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_pacific_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_pacific_31.Visible = 'off';
            app.hat_pacific_31.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_pacific_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_pacific_31.Visible = 'off';
            app.battleship_pacific_31.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_pacific_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_pacific_31.Visible = 'off';
            app.dog_pacific_31.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_north_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_north_32.Visible = 'off';
            app.car_north_32.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_north_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_north_32.Visible = 'off';
            app.hat_north_32.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_north_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_north_32.Visible = 'off';
            app.battleship_north_32.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_north_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_north_32.Visible = 'off';
            app.dog_north_32.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_comm_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_comm_33.Visible = 'off';
            app.car_comm_33.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_comm_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_comm_33.Visible = 'off';
            app.hat_comm_33.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_comm_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_comm_33.Visible = 'off';
            app.battleship_comm_33.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_comm_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_comm_33.Visible = 'off';
            app.dog_comm_33.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_pennsylvania_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_pennsylvania_34.Visible = 'off';
            app.car_pennsylvania_34.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_pennsylvania_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_pennsylvania_34.Visible = 'off';
            app.hat_pennsylvania_34.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_pennsylvania_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_pennsylvania_34.Visible = 'off';
            app.battleship_pennsylvania_34.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_pennsylvania_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_pennsylvania_34.Visible = 'off';
            app.dog_pennsylvania_34.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_short_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_short_35.Visible = 'off';
            app.car_short_35.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_short_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_short_35.Visible = 'off';
            app.hat_short_35.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_short_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_short_35.Visible = 'off';
            app.battleship_short_35.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_short_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_short_35.Visible = 'off';
            app.dog_short_35.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_chance_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_chance_36.Visible = 'off';
            app.car_chance_36.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_chance_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_chance_36.Visible = 'off';
            app.hat_chance_36.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_chance_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_chance_36.Visible = 'off';
            app.battleship_chance_36.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_chance_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_chance_36.Visible = 'off';
            app.dog_chance_36.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_park_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_park_37.Visible = 'off';
            app.car_park_37.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_park_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_park_37.Visible = 'off';
            app.hat_park_37.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_park_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_park_37.Visible = 'off';
            app.battleship_park_37.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_park_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_park_37.Visible = 'off';
            app.dog_park_37.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_luxary_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_luxary_38.Visible = 'off';
            app.car_luxary_38.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_luxary_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_luxary_38.Visible = 'off';
            app.hat_luxary_38.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_luxary_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_luxary_38.Visible = 'off';
            app.battleship_luxary_38.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_luxary_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_luxary_38.Visible = 'off';
            app.dog_luxary_38.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_boardwalk_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_boardwalk_39.Visible = 'off';
            app.car_boardwalk_39.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_boardwalk_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_boardwalk_39.Visible = 'off';
            app.hat_boardwalk_39.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_boardwalk_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_boardwalk_39.Visible = 'off';
            app.battleship_boardwalk_39.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_boardwalk_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_boardwalk_39.Visible = 'off';
            app.dog_boardwalk_39.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_stcharles_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_stcharles_11.Visible = 'off';
            app.car_stcharles_11.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_stcharles_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_stcharles_11.Visible = 'off';
            app.hat_stcharles_11.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_stcharles_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_stcharles_11.Visible = 'off';
            app.battleship_stcharles_11.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_stcharles_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_stcharles_11.Visible = 'off';
            app.dog_stcharles_11.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_electric_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_electric_12.Visible = 'off';
            app.car_electric_12.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_electric_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_electric_12.Visible = 'off';
            app.hat_electric_12.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_electric_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_electric_12.Visible = 'off';
            app.battleship_electric_12.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_electric_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_electric_12.Visible = 'off';
            app.dog_electric_12.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_states_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_states_13.Visible = 'off';
            app.car_states_13.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_states_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_states_13.Visible = 'off';
            app.hat_states_13.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_states_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_states_13.Visible = 'off';
            app.battleship_states_13.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_states_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_states_13.Visible = 'off';
            app.dog_states_13.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_virginia_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_virginia_14.Visible = 'off';
            app.car_virginia_14.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_virginia_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_virginia_14.Visible = 'off';
            app.hat_virginia_14.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_virginia_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_virginia_14.Visible = 'off';
            app.battleship_virginia_14.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_virginia_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_virginia_14.Visible = 'off';
            app.dog_virginia_14.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_pennsylvania_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_pennsylvania_15.Visible = 'off';
            app.car_pennsylvania_15.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_pennsylvania_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_pennsylvania_15.Visible = 'off';
            app.hat_pennsylvania_15.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_pennsylvania_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_pennsylvania_15.Visible = 'off';
            app.battleship_pennsylvania_15.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_pennsylvania_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_pennsylvania_15.Visible = 'off';
            app.dog_pennsylvania_15.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_stjames_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_stjames_16.Visible = 'off';
            app.car_stjames_16.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_stjames_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_stjames_16.Visible = 'off';
            app.hat_stjames_16.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_stjames_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_stjames_16.Visible = 'off';
            app.battleship_stjames_16.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_stjames_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_stjames_16.Visible = 'off';
            app.dog_stjames_16.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_comm_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_comm_17.Visible = 'off';
            app.car_comm_17.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_comm_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_comm_17.Visible = 'off';
            app.hat_comm_17.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_comm_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_comm_17.Visible = 'off';
            app.battleship_comm_17.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_comm_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_comm_17.Visible = 'off';
            app.dog_comm_17.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_tennessee_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_tennessee_18.Visible = 'off';
            app.car_tennessee_18.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_tennessee_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_tennessee_18.Visible = 'off';
            app.hat_tennessee_18.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_tennessee_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_tennessee_18.Visible = 'off';
            app.battleship_tennessee_18.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_tennessee_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_tennessee_18.Visible = 'off';
            app.dog_tennessee_18.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_new_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_new_19.Visible = 'off';
            app.car_new_19.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_new_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_new_19.Visible = 'off';
            app.hat_new_19.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_new_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_new_19.Visible = 'off';
            app.battleship_new_19.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_new_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_new_19.Visible = 'off';
            app.dog_new_19.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end