classdef room_browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure  matlab.ui.Figure
        NALabel                   matlab.ui.control.Label
        BackButton                matlab.ui.control.Button
        RoomNameLabel             matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        NoneLabel                 matlab.ui.control.Label
        PlayersConnectedNALabel   matlab.ui.control.Label
        NoRoomSelectedButton      matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        ListBox                   matlab.ui.control.ListBox
    end

    properties (Access = private)
        Rooms % game rooms found
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import matlab.net.*
            import matlab.net.http.*

            data = struct("roomName", "balls", "hostUsername", "tony");
            requestMethod = RequestMethod.POST;
            requestHeaders = HeaderField("Content-Type", "application/json");
            body = MessageBody(data);
            requestMessage = RequestMessage(requestMethod, requestHeaders, body);
            uri = URI("http://localhost:8000/menu/rooms");
            response = send(RequestMessage, uri);
            if (~isempty(response.Body.Data))
                for room = response.Body.Data
                    app.Rooms{end+1} = room;
                    app.ListBox.Items{end+1} = room.roomName;
                end
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app);
            main_menu();
        end

        % Button pushed function: NoRoomSelectedButton
        function NoRoomSelectedButtonPushed(app, event)
            delete(app);
            game();
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.NALabel.Text = app.Rooms{item}.roomName;
            numPlayersConnected = length(app.Rooms{item}.players);
            app.PlayersConnectedNALabel.Text = sprintf("Players Connected(%d):", numPlayersConnected);
            if(numPlayersConnected > 0)
                app.NoneLabel.Text = "";
                for player = app.Rooms{item}.players
                    app.NoneLabel.Text = append(app.NoneLabel.Text, app.Rooms{item}.players.playerName, "\n");
                end
            end

            if(app.Rooms{item}.started)
                app.NoRoomSelectedButton.Text = "Game Already Started";
                app.NoRoomSelectedButton.Enable = "off";
            else
                app.NoRoomSelectedButton.Text = "Join Game";
                app.NoRoomSelectedButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.ListBox = uilistbox(app.MonopolyInMatlabUIFigure);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.Image = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image.ImageSource = 'Monopoly_game_logo.svg';
            app.NoRoomSelectedButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.NoRoomSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @NoRoomSelectedButtonPushed, true);
            app.NoRoomSelectedButton.Enable = 'off';
            app.NoRoomSelectedButton.Text = 'No Room Selected';
            app.PlayersConnectedNALabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.PlayersConnectedNALabel.Text = 'Players Connected(N/A):';
            app.NoneLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.NoneLabel.Text = 'None';
            app.Image_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image_2.ImageSource = 'Matlab_Logo.png';
            app.XLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.XLabel.Text = 'X';
            app.RoomNameLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.RoomNameLabel.Text = 'Room Name:';
            app.BackButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NALabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.NALabel.Text = 'N/A';
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = room_browser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end